classdef ioClinCal_normStim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ContactParametersPanel     matlab.ui.container.Panel
        mA60uS130HzEditFieldLabel  matlab.ui.control.Label
        mA60uS130HzEditField       matlab.ui.control.NumericEditField
        TImpedanceEditFieldLabel   matlab.ui.control.Label
        TImpedanceEditField        matlab.ui.control.NumericEditField
        VoltageEditFieldLabel      matlab.ui.control.Label
        VoltageEditField           matlab.ui.control.NumericEditField
        CalculateVoltageButton     matlab.ui.control.Button
        uSecsPWPanel               matlab.ui.container.Panel
        TEEDEditField_2Label       matlab.ui.control.Label
        TEEDEditField_2            matlab.ui.control.NumericEditField
        ImpedEditFieldLabel        matlab.ui.control.Label
        ImpedEditField             matlab.ui.control.NumericEditField
        FreqEditFieldLabel         matlab.ui.control.Label
        FreqEditField              matlab.ui.control.NumericEditField
        pwEditFieldLabel           matlab.ui.control.Label
        pwEditField                matlab.ui.control.NumericEditField
        Calculate30sButton         matlab.ui.control.Button
        mAEditFieldLabel           matlab.ui.control.Label
        mAEditField                matlab.ui.control.NumericEditField
        uSecsPWPanel_2             matlab.ui.container.Panel
        TEEDEditField_3Label       matlab.ui.control.Label
        TEEDEditField_3            matlab.ui.control.NumericEditField
        ImpedEditField_2Label      matlab.ui.control.Label
        ImpedEditField_2           matlab.ui.control.NumericEditField
        FreqEditField_2Label       matlab.ui.control.Label
        FreqEditField_2            matlab.ui.control.NumericEditField
        pwEditField_2Label         matlab.ui.control.Label
        pwEditField_2              matlab.ui.control.NumericEditField
        Calculate90sButton         matlab.ui.control.Button
        mAEditField_2Label         matlab.ui.control.Label
        mAEditField_2              matlab.ui.control.NumericEditField
        CalculateTEEDPanel         matlab.ui.container.Panel
        CalculateTEEDButton        matlab.ui.control.Button
        TEEDEditFieldLabel         matlab.ui.control.Label
        TEEDEditField              matlab.ui.control.NumericEditField
        RandomizeTreatmentsPanel   matlab.ui.container.Panel
        RandomizeButton            matlab.ui.control.Button
        Treatment1EditFieldLabel   matlab.ui.control.Label
        Treatment1EditField        matlab.ui.control.EditField
        Treatment2EditFieldLabel   matlab.ui.control.Label
        Treatment2EditField        matlab.ui.control.EditField
        Treatment3EditFieldLabel   matlab.ui.control.Label
        Treatment3EditField        matlab.ui.control.EditField
        Treatment4EditFieldLabel   matlab.ui.control.Label
        Treatment4EditField        matlab.ui.control.EditField
        Treatment5EditFieldLabel   matlab.ui.control.Label
        Treatment5EditField        matlab.ui.control.EditField
        IncomingStimulationPanel   matlab.ui.container.Panel
        SideDropDownLabel          matlab.ui.control.Label
        SideDropDown               matlab.ui.control.DropDown
        TypeDropDownLabel          matlab.ui.control.Label
        TypeDropDown               matlab.ui.control.DropDown
        CathodeEditFieldLabel      matlab.ui.control.Label
        CathodeEditField           matlab.ui.control.EditField
        Anode1EditFieldLabel       matlab.ui.control.Label
        Anode1EditField            matlab.ui.control.EditField
        Anode2EditFieldLabel       matlab.ui.control.Label
        Anode2EditField            matlab.ui.control.EditField
        mAEditField_3Label         matlab.ui.control.Label
        mAEditField_3              matlab.ui.control.EditField
        FreqEditField_3Label       matlab.ui.control.Label
        FreqEditField_3            matlab.ui.control.EditField
        mAEditField_4Label         matlab.ui.control.Label
        mAEditField_4              matlab.ui.control.EditField
        FreqEditField_4Label       matlab.ui.control.Label
        FreqEditField_4            matlab.ui.control.EditField
    end

    
    properties (Access = public)
        normStim
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateTEEDButton
        function CalculateTEEDButtonPushed(app, event)
            pw = 60; % microseconds
            frq = 130; % hertz
            voltage = (app.VoltageEditField.Value)^2;
            impedance = app.TImpedanceEditField.Value;
            
            teed = ((voltage * frq * pw) / impedance) * 1;
            
            app.TEEDEditField.Value = teed;
            
            
            app.TEEDEditField_2.Value = teed;
            app.ImpedEditField.Value = impedance;
            app.FreqEditField.Value = frq;
            
            app.TEEDEditField_3.Value = teed;
            app.ImpedEditField_2.Value = impedance;
            app.FreqEditField_2.Value = frq;

            
        end

        % Button pushed function: Calculate30sButton
        function Calculate30sButtonPushed(app, event)
            teed = app.TEEDEditField_2.Value;
            imped = app.ImpedEditField.Value;
            frq = app.FreqEditField.Value;
            microSecs = 30;
            
            voltage2 = (teed * imped) / (frq * microSecs);
            voltage = sqrt(voltage2);
            current = voltage / imped;
            
            app.mAEditField.Value = round(current*1000,2);
            
        end

        % Button pushed function: Calculate90sButton
        function Calculate90sButtonPushed(app, event)
            teed = app.TEEDEditField_3.Value;
            imped = app.ImpedEditField_2.Value;
            frq = app.FreqEditField_2.Value;
            microSecs = 90;
            
            voltage2 = (teed * imped) / (frq * microSecs);
            voltage = sqrt(voltage2);
            current = voltage / imped;
            
            app.mAEditField_2.Value = round(current*1000,2);
        end

        % Button pushed function: CalculateVoltageButton
        function CalculateVoltageButtonPushed(app, event)
            voltage = (app.TImpedanceEditField.Value * app.mA60uS130HzEditField.Value)/1000;
            
            app.VoltageEditField.Value = voltage;
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
           thirtymicro = append(string(app.mAEditField.Value),'mA', ' ', '30us');
           sixtymicro =  append(string(app.mA60uS130HzEditField.Value),'mA', ' ', '60us'); 
           ninetymicro = append(string(app.mAEditField_2.Value), 'mA', ' ', '90us');
           treatments = {'No Stimulation', app.normStim, thirtymicro, sixtymicro, ninetymicro};
           randTreatments = randperm(5); 
           app.Treatment1EditField.Value = treatments{randTreatments(1)};
           app.Treatment2EditField.Value = treatments{randTreatments(2)}; 
           app.Treatment3EditField.Value = treatments{randTreatments(3)};
           app.Treatment4EditField.Value = treatments{randTreatments(4)};
           app.Treatment5EditField.Value = treatments{randTreatments(5)};
           
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            if value == "Monopolar" 
                app.Anode2EditField.Visible = "off";
                app.mAEditField_4.Visible = "off"; 
                app.FreqEditField_4.Visible = "off"; 
                normStimType = "Mono"; 
                normStimCath = append(string(app.CathodeEditField.Value), "-"); 
                normStimAnod = append(string(app.Anode1EditField.Value), "+");
                normStimMa = string(app.mAEditField_3.Value); 
                normStimFreq = string(app.FreqEditField_3.Value); 
                app.normStim = append(normStimType, ' ', normStimCath, ' ',normStimAnod,' ', normStimMa,'mA ', normStimFreq, 'Hz');
            elseif value == "Bipolar" 
                normStimType = "Bi"; 
                normStimCath = append(string(app.CathodeEditField.Value), "-"); 
                normStimAnod = append(string(app.Anode1EditField.Value), "+");
                normStimMa = string(app.mAEditField_3.Value); 
                normStimFreq = string(app.FreqEditField_3.Value); 
                normStim1 = append(normStimType, ' ', normStimCath, ' ',normStimAnod,' ', normStimMa,'mA ', normStimFreq, 'Hz');
                normStimAnod2 = append(string(app.Anode2EditField.Value), "+");
                normStimMa2 = string(app.mAEditField_4.Value); 
                normStimFreq2 = string(app.FreqEditField_4.Value); 
                normStim2 = append(normStimType, ' ', normStimCath, ' ',normStimAnod2,' ', normStimMa2,'mA ', normStimFreq2, 'Hz');
                app.normStim = append(normStim1, ' ', normStim2); 
            elseif value == "Interleaving"
                normStimType = "In"; 
                normStimCath = append(string(app.CathodeEditField.Value), "-"); 
                normStimAnod = append(string(app.Anode1EditField.Value), "+");
                normStimMa = string(app.mAEditField_3.Value); 
                normStimFreq = string(app.FreqEditField_3.Value); 
                normStim1 = append(normStimType, ' ', normStimCath, ' ',normStimAnod,' ', normStimMa,'mA ', normStimFreq, 'Hz');
                normStimAnod2 = append(string(app.Anode2EditField.Value), "+");
                normStimMa2 = string(app.mAEditField_4.Value); 
                normStimFreq2 = string(app.FreqEditField_4.Value); 
                normStim2 = append(normStimType, ' ', normStimCath, ' ',normStimAnod2,' ', normStimMa2,'mA ', normStimFreq2, 'Hz');
                app.normStim = append(normStim1, ' ', normStim2); 
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ContactParametersPanel = uipanel(app.UIFigure);
            app.ContactParametersPanel.Title = 'Contact Parameters';
            app.mA60uS130HzEditFieldLabel = uilabel(app.ContactParametersPanel);
            app.mA60uS130HzEditFieldLabel.Text = 'mA (60 uS /130 Hz)';
            app.mA60uS130HzEditField = uieditfield(app.ContactParametersPanel, 'numeric');
            app.TImpedanceEditFieldLabel = uilabel(app.ContactParametersPanel);
            app.TImpedanceEditFieldLabel.Text = 'T. Impedance';
            app.TImpedanceEditField = uieditfield(app.ContactParametersPanel, 'numeric');
            app.VoltageEditFieldLabel = uilabel(app.ContactParametersPanel);
            app.VoltageEditFieldLabel.Text = 'Voltage';
            app.VoltageEditField = uieditfield(app.ContactParametersPanel, 'numeric');
            app.VoltageEditField.Editable = 'off';
            app.CalculateVoltageButton = uibutton(app.ContactParametersPanel, 'push');
            app.CalculateVoltageButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateVoltageButtonPushed, true);
            app.CalculateVoltageButton.Text = 'Calculate Voltage';
            app.uSecsPWPanel = uipanel(app.UIFigure);
            app.uSecsPWPanel.Title = '30 uSecs PW';
            app.TEEDEditField_2Label = uilabel(app.uSecsPWPanel);
            app.TEEDEditField_2Label.Text = 'TEED';
            app.TEEDEditField_2 = uieditfield(app.uSecsPWPanel, 'numeric');
            app.TEEDEditField_2.Editable = 'off';
            app.ImpedEditFieldLabel = uilabel(app.uSecsPWPanel);
            app.ImpedEditFieldLabel.Text = 'Imped';
            app.ImpedEditField = uieditfield(app.uSecsPWPanel, 'numeric');
            app.ImpedEditField.Editable = 'off';
            app.FreqEditFieldLabel = uilabel(app.uSecsPWPanel);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.FreqEditField = uieditfield(app.uSecsPWPanel, 'numeric');
            app.FreqEditField.Editable = 'off';
            app.pwEditFieldLabel = uilabel(app.uSecsPWPanel);
            app.pwEditFieldLabel.Text = 'pw';
            app.pwEditField = uieditfield(app.uSecsPWPanel, 'numeric');
            app.pwEditField.Editable = 'off';
            app.pwEditField.Value = 30;
            app.Calculate30sButton = uibutton(app.uSecsPWPanel, 'push');
            app.Calculate30sButton.ButtonPushedFcn = createCallbackFcn(app, @Calculate30sButtonPushed, true);
            app.Calculate30sButton.Text = 'Calculate 30s';
            app.mAEditFieldLabel = uilabel(app.uSecsPWPanel);
            app.mAEditFieldLabel.Text = 'mA';
            app.mAEditField = uieditfield(app.uSecsPWPanel, 'numeric');
            app.mAEditField.Editable = 'off';
            app.uSecsPWPanel_2 = uipanel(app.UIFigure);
            app.uSecsPWPanel_2.Title = '90 uSecs PW';
            app.TEEDEditField_3Label = uilabel(app.uSecsPWPanel_2);
            app.TEEDEditField_3Label.Text = 'TEED';
            app.TEEDEditField_3 = uieditfield(app.uSecsPWPanel_2, 'numeric');
            app.TEEDEditField_3.Editable = 'off';
            app.ImpedEditField_2Label = uilabel(app.uSecsPWPanel_2);
            app.ImpedEditField_2Label.Text = 'Imped';
            app.ImpedEditField_2 = uieditfield(app.uSecsPWPanel_2, 'numeric');
            app.ImpedEditField_2.Editable = 'off';
            app.FreqEditField_2Label = uilabel(app.uSecsPWPanel_2);
            app.FreqEditField_2Label.Text = 'Freq';
            app.FreqEditField_2 = uieditfield(app.uSecsPWPanel_2, 'numeric');
            app.FreqEditField_2.Editable = 'off';
            app.pwEditField_2Label = uilabel(app.uSecsPWPanel_2);
            app.pwEditField_2Label.Text = 'pw';
            app.pwEditField_2 = uieditfield(app.uSecsPWPanel_2, 'numeric');
            app.pwEditField_2.Editable = 'off';
            app.pwEditField_2.Value = 90;
            app.Calculate90sButton = uibutton(app.uSecsPWPanel_2, 'push');
            app.Calculate90sButton.ButtonPushedFcn = createCallbackFcn(app, @Calculate90sButtonPushed, true);
            app.Calculate90sButton.Text = 'Calculate 90s';
            app.mAEditField_2Label = uilabel(app.uSecsPWPanel_2);
            app.mAEditField_2Label.Text = 'mA';
            app.mAEditField_2 = uieditfield(app.uSecsPWPanel_2, 'numeric');
            app.mAEditField_2.Editable = 'off';
            app.CalculateTEEDPanel = uipanel(app.UIFigure);
            app.CalculateTEEDPanel.Title = 'Calculate TEED';
            app.CalculateTEEDButton = uibutton(app.CalculateTEEDPanel, 'push');
            app.CalculateTEEDButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateTEEDButtonPushed, true);
            app.CalculateTEEDButton.Text = 'Calculate TEED';
            app.TEEDEditFieldLabel = uilabel(app.CalculateTEEDPanel);
            app.TEEDEditFieldLabel.Text = 'TEED';
            app.TEEDEditField = uieditfield(app.CalculateTEEDPanel, 'numeric');
            app.TEEDEditField.Editable = 'off';
            app.RandomizeTreatmentsPanel = uipanel(app.UIFigure);
            app.RandomizeTreatmentsPanel.Title = 'Randomize Treatments';
            app.RandomizeButton = uibutton(app.RandomizeTreatmentsPanel, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Text = 'Randomize';
            app.Treatment1EditFieldLabel = uilabel(app.RandomizeTreatmentsPanel);
            app.Treatment1EditFieldLabel.Text = 'Treatment 1';
            app.Treatment1EditField = uieditfield(app.RandomizeTreatmentsPanel, 'text');
            app.Treatment2EditFieldLabel = uilabel(app.RandomizeTreatmentsPanel);
            app.Treatment2EditFieldLabel.Text = 'Treatment 2';
            app.Treatment2EditField = uieditfield(app.RandomizeTreatmentsPanel, 'text');
            app.Treatment3EditFieldLabel = uilabel(app.RandomizeTreatmentsPanel);
            app.Treatment3EditFieldLabel.Text = 'Treatment 3';
            app.Treatment3EditField = uieditfield(app.RandomizeTreatmentsPanel, 'text');
            app.Treatment4EditFieldLabel = uilabel(app.RandomizeTreatmentsPanel);
            app.Treatment4EditFieldLabel.Text = 'Treatment 4';
            app.Treatment4EditField = uieditfield(app.RandomizeTreatmentsPanel, 'text');
            app.Treatment5EditFieldLabel = uilabel(app.RandomizeTreatmentsPanel);
            app.Treatment5EditFieldLabel.Text = 'Treatment 5';
            app.Treatment5EditField = uieditfield(app.RandomizeTreatmentsPanel, 'text');
            app.IncomingStimulationPanel = uipanel(app.UIFigure);
            app.IncomingStimulationPanel.Title = 'Incoming Stimulation';
            app.SideDropDownLabel = uilabel(app.IncomingStimulationPanel);
            app.SideDropDownLabel.Text = 'Side';
            app.SideDropDown = uidropdown(app.IncomingStimulationPanel);
            app.SideDropDown.Items = {'Right', 'Left', '', ''};
            app.SideDropDown.Value = '';
            app.TypeDropDownLabel = uilabel(app.IncomingStimulationPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.IncomingStimulationPanel);
            app.TypeDropDown.Items = {'Monopolar', 'Bipolar', 'Interleaving', ''};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = '';
            app.CathodeEditFieldLabel = uilabel(app.IncomingStimulationPanel);
            app.CathodeEditFieldLabel.Text = 'Cathode';
            app.CathodeEditField = uieditfield(app.IncomingStimulationPanel, 'text');
            app.Anode1EditFieldLabel = uilabel(app.IncomingStimulationPanel);
            app.Anode1EditFieldLabel.Text = 'Anode 1';
            app.Anode1EditField = uieditfield(app.IncomingStimulationPanel, 'text');
            app.Anode2EditFieldLabel = uilabel(app.IncomingStimulationPanel);
            app.Anode2EditFieldLabel.Text = 'Anode 2';
            app.Anode2EditField = uieditfield(app.IncomingStimulationPanel, 'text');
            app.mAEditField_3Label = uilabel(app.IncomingStimulationPanel);
            app.mAEditField_3Label.Text = 'mA';
            app.mAEditField_3 = uieditfield(app.IncomingStimulationPanel, 'text');
            app.FreqEditField_3Label = uilabel(app.IncomingStimulationPanel);
            app.FreqEditField_3Label.Text = 'Freq';
            app.FreqEditField_3 = uieditfield(app.IncomingStimulationPanel, 'text');
            app.mAEditField_4Label = uilabel(app.IncomingStimulationPanel);
            app.mAEditField_4Label.Text = 'mA';
            app.mAEditField_4 = uieditfield(app.IncomingStimulationPanel, 'text');
            app.FreqEditField_4Label = uilabel(app.IncomingStimulationPanel);
            app.FreqEditField_4Label.Text = 'Freq';
            app.FreqEditField_4 = uieditfield(app.IncomingStimulationPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ioClinCal_normStim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end